classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PointsforplotSlider           matlab.ui.control.Slider
        PointsforplotSliderLabel      matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        LoadFileholoandavionlyButton  matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        hologram % Description
        hologram_loaded = false
        plot_printed = false
        picture
        filepath
        filename
        x
        y
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileholoandavionlyButton
        function LoadFileholoandavionlyButtonPushed(app, event)
            app.Label_2.Text = "Waiting for a .holo or a .avi file";
            if (isempty(app.filepath))
                [fname, fpath] = uigetfile('*.holo;*.avi');
            else
                [fname, fpath] = uigetfile(strcat(app.filepath, "*.holo;*.avi"));
            end
            fullname = fullfile(fpath, fname);
            if fname == 0
                return
            end
            [fpath, fname, fext] = fileparts(fullname);
            app.filepath = fpath;
            app.filename = fname;
            app.Label_2.Text = "Loading data...";
            switch(fext)
                case '.holo'
                    app.hologram = Read_Holo(fullname);
                case '.avi'
                    app.hologram = [];
                    v = VideoReader(fullname);
                    while hasFrame(v)
                        frame = readFrame(v);
                        app.hologram = cat(3, app.hologram, frame);
                    end
            end
            app.Label.Text = fullname;
            disp(ndims(app.hologram))
            app.hologram_loaded = true;
            app.plot_printed = false;
            app.picture = mean(mat2gray(app.hologram), 3);
            rgb_picture = cat(3, app.picture, app.picture);
            rgb_picture = cat(3, rgb_picture, app.picture);
            app.Image.ImageSource = rgb_picture;
            app.Label_2.Text = strcat(fullname, " loaded");
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if (app.hologram_loaded == false)
                return;
            end
            figure(1)
            imshow(app.picture);
            app.Label_2.Text = "Please choose a point to analyse";
            points_selected = 0;
            points_x = [];
            points_y = [];
            app.Label_2.Text = "Calculations...";
            while (points_selected < round(app.PointsforplotSlider.Value))
                [x_click, y_click] = ginput(1);
                if (points_selected == 0)
                    app.x = round(x_click);
                    app.y = round(y_click);
                end
                points_x(end + 1) = round(x_click);
                points_y(end + 1) = round(y_click);
                points_selected = points_selected + 1;
            end
            points_x(end + 1) = points_x(1);
            points_y(end + 1) = points_y(1);
            evolution = [];
            for i = min(points_x):max(points_x)
                for j = min(points_y):max(points_y)
                    if (inpolygon(i, j, points_x, points_y))
                        if (size(evolution) == 0)
                            evolution = [app.hologram(i,j,:)];
                        else
                            evolution = cat(1, evolution, app.hologram(i,j,:));
                        end
                    end
                end
            end
            disp(ndims(evolution))
            disp(size(evolution, 1))
            disp(size(evolution, 2))

            evolution = mean(evolution, 1);
            app.Label_2.Text = "Printing plot...";
            plot(app.UIAxes, squeeze(evolution(1,:)));
            app.Label_2.Text = strcat("Plot printed for (", int2str(app.x), ", ", int2str(app.y), ")");
            app.plot_printed = true;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if (app.plot_printed == true)
                app.Label_2.Text = "Saving...";
                savename = fullfile(app.filepath, strcat(app.filename, "_plot_", int2str(app.x), "_", int2str(app.y), ".png"));
                exportgraphics(app.UIAxes, savename);
                app.Label_2.Text = strcat("Plot saved as ", savename);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.LoadFileholoandavionlyButton = uibutton(app.UIFigure, 'push');
            app.LoadFileholoandavionlyButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileholoandavionlyButtonPushed, true);
            app.LoadFileholoandavionlyButton.Text = 'Load File (.holo and .avi only)';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.PointsforplotSliderLabel = uilabel(app.UIFigure);
            app.PointsforplotSliderLabel.Text = 'Points for plot';
            app.PointsforplotSlider = uislider(app.UIFigure);
            app.PointsforplotSlider.Limits = [1 9];
            app.PointsforplotSlider.MajorTicks = [1 3 5 7 9];
            app.PointsforplotSlider.MinorTicks = [2 4 6 8];
            app.PointsforplotSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end